classdef HW1_11125016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label_5          matlab.ui.control.Label
        M11125016_Label  matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Label_1          matlab.ui.control.Label
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_1         matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        seq = randperm(4); % 四按鈕變紅的隨機順序，randperm(4)對數值1～4隨機排序
        p = zeros(1,4); % 四按鈕變紅與被按下的標記
        t_start = zeros(1,4); % 四按鈕變紅的(起始)時間
        t_end = zeros(1,4); % 四按鈕被按下的(結束)時間
        im; % 影像資料
    end
    
    methods (Access = private)
        
        function func1(app)
%             uigetfile() 讀取影像檔案的名稱與路徑。
            [filenmae, pathname] = uigetfile({'*.jpg'}, 'select image');
%             imread() 讀取該[路徑, 檔名]的影像陣列資料到app.im
            app.im = imread([pathname, filenmae]);
%             用 size() 讀取影像高寬
            [row, col, ~] = size(app.im);
%             調整影像高寬使影像上下左右密接
            if row > col
                app.Button_1.HorizontalAlignment = "right";
                app.Button_2.HorizontalAlignment = "left";
                app.Button_3.HorizontalAlignment = "right";
                app.Button_4.HorizontalAlignment = "left";
            else
                app.Button_1.VerticalAlignment = "bottom";
                app.Button_2.VerticalAlignment = "bottom";
                app.Button_3.VerticalAlignment = "top";
                app.Button_4.VerticalAlignment = "top";
            end
%             建立跑四次的迴圈，選擇switch/cases做Backgroundcolor更改、計時...
%             、狀態設定
            for i = app.seq
                pause(1.5)
                switch i
                    case 1
                        app.Button_1.BackgroundColor = "r";
                        app.t_start(1) = tic;
                        app.p(1) = true; % 記錄已亮起
                    case 2
                        app.Button_2.BackgroundColor = "r";
                        app.t_start(2) = tic;
                        app.p(2) = true; % 記錄已亮起
                    case 3
                        app.Button_3.BackgroundColor = "r";
                        app.t_start(3) = tic;
                        app.p(3) = true; % 記錄已亮起
                    case 4
                        app.Button_4.BackgroundColor = "r";
                        app.t_start(4) = tic;
                        app.p(4) = true; % 記錄已亮起
                end

            end
        end
%         if all button has pushed, this function will be run
        function func2(app)
            cor = [2 2 2 2];
            if isequal(app.p, cor)
%                 The flashing background with red/white
                for i = (1:11)
                    pause(0.1)
                    if mod(i, 2) == 0
                        app.UIFigure.Color = "r";
                    else
                        app.UIFigure.Color = "w";
                    end
                end
%                 Let the top label down
                pos = app.M11125016_Label.Position(2);
                steps = round(pos / 20) + 2;
                disp(steps)
                for i = (1:steps)
                    pause(0.08)
                    app.M11125016_Label.Position(2) = ...
                        app.M11125016_Label.Position(2) - 20;
                end
%                 呈現總分
                total_time = sum(app.t_end);
                app.Label_5.Text = sprintf("您一共花了%0.1f秒",total_time);
                app.Label_5.Visible = "on";
                pause(4)
                app.delete
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % 對Label.Text做初始化(空白化)
            app.Label_1.Visible = "off";
            app.Label_2.Visible = "off";
            app.Label_3.Visible = "off";
            app.Label_4.Visible = "off";
            app.Label_5.Visible = "off";
            func1(app); % 讀取影像，控制按鈕變紅的順序與時間
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            if app.p(1) == true
                [row, col, ~] = size(app.im);
                crop_part = [1 1 col/2 row/2];
                app.Button_1.Icon = imcrop(app.im, crop_part);
                app.t_end(1) = toc(uint64(app.t_start(1)));
                app.Label_1.Text = num2str(app.t_end(1));
                app.Label_1.Visible = "on";
                app.p(1) = "2";
                func2(app)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.p(2) == true
                [row, col, ~] = size(app.im); % read image size
                crop_part = [col/2 1 col row/2]; % image crop part
                app.Button_2.Icon = imcrop(app.im, crop_part); % crop and show
                app.t_end(2) = toc(uint64(app.t_start(2))); % stop and save the time
                app.Label_2.Text = num2str(app.t_end(2));
                app.Label_2.Visible = "on";
                app.p(2) = "2"; % saved the button status as pushed
                func2(app);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.p(3) == true
                [row, col, ~] = size(app.im);
                crop_part = [1 row/2 col/2 row];
                app.Button_3.Icon = imcrop(app.im, crop_part);
                app.t_end(3) = toc(uint64(app.t_start(3)));
                app.Label_3.Text = num2str(app.t_end(3));
                app.Label_3.Visible = "on";
                app.p(3) = "2";
                func2(app);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.p(4) == true
                [row, col, ~] = size(app.im);
                crop_part = [col/2 row/2 col row];
                app.Button_4.Icon = imcrop(app.im, crop_part);
                app.t_end(4) = toc(uint64(app.t_start(4)));
                app.Label_4.Text = num2str(app.t_end(4));
                app.Label_4.Visible = "on";
                app.p(4) = "2";
                func2(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.M11125016_Label = uilabel(app.UIFigure);
            app.M11125016_Label.Text = 'M11125016_影像打地鼠';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1_11125016

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end