classdef hw2_M11125016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        OpenMenu           matlab.ui.container.Menu
        SaveasMenu         matlab.ui.container.Menu
        ExitMenu           matlab.ui.container.Menu
        ProcessMenu        matlab.ui.container.Menu
        NegativeMenu       matlab.ui.container.Menu
        FlipMenu           matlab.ui.container.Menu
        BlurMenu           matlab.ui.container.Menu
        WatermarkMenu      matlab.ui.container.Menu
        RightPanel         matlab.ui.container.Panel
        DeleteStatusLabel  matlab.ui.control.Label
        FilterLabel        matlab.ui.control.Label
        RightImage         matlab.ui.control.Image
        LeftPanel          matlab.ui.container.Panel
        DELETButton        matlab.ui.control.StateButton
        TimeLabel          matlab.ui.control.Label
        Slider             matlab.ui.control.Slider
        SliderLabel        matlab.ui.control.Label
        PlayPauseButton    matlab.ui.control.StateButton
        LeftImage          matlab.ui.control.Image
        ContextMenu        matlab.ui.container.ContextMenu
        Menu               matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Property % Description
        vidObj % Video object
        im % current image
        p = 1 % image process options
        delete_list% The part of deleted from starting time and ending time
        strl = "M11125016" % watermarkers
    end
    
    methods (Access = private)
        
        function results = func(app, im)
            switch app.p
                case 1
                    results = im;
                    app.FilterLabel.Text = 'Normal';
                case 2 % negative
                    results = 255 - im;
                    app.FilterLabel.Text = 'Negative';
                case 3 % image flip
                    results = fliplr(im);
                    app.FilterLabel.Text = "Image flip";
                case 4 % Blur
                    results = imgaussfilt(im,3);
                    app.FilterLabel.Text = "Gaussion Flitter";
            end
            % If pushed delete, the image will turn black until the delete
            % button pushed again. 
            if app.DELETButton.Value 
                results = results - 100;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % stretch the image component
            app.LeftImage.ScaleMethod = "stretch";
            app.RightImage.ScaleMethod = "stretch";
            % disable the menu component
            app.DeleteStatusLabel.Visible = "off";
            app.SaveasMenu.Enable = "off";
            app.ProcessMenu.Enable = "off";
            app.WatermarkMenu.Enable = "off";
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % enable the menu component
            app.SaveasMenu.Enable = "on";
            app.ProcessMenu.Enable = "on";
            app.WatermarkMenu.Enable = "on";

            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            % Build video object
            app.vidObj = VideoReader([pathname filename]);
            app.im = read(app.vidObj, 1); %Read first frame

            % Set video component
            app.LeftImage.ImageSource = app.im;
            app.RightImage.ImageSource = func(app, app.im);

            % Set slider
            app.Slider.Limits(2) = app.vidObj.Duration;
            app.Slider.Value = app.vidObj.CurrentTime;
            
            % Set component's icon image
            app.PlayPauseButton.Icon = 'down.jpg';
            app.TimeLabel.Text = '0';
            figure(app.UIFigure)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.p = 2;
            app.RightImage.ImageSource = func(app, app.im);
        end

        % Menu selected function: FlipMenu
        function FlipMenuSelected(app, event)
            app.p = 3;
            app.RightImage.ImageSource = func(app, app.im);
        end

        % Menu selected function: BlurMenu
        function BlurMenuSelected(app, event)
            app.p = 4;
            app.RightImage.ImageSource = func(app, app.im);
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if value
%                 read(app.vidObj,1);
                app.PlayPauseButton.Icon = 'pause.jpg';                
                while hasFrame(app.vidObj)
                    if app.PlayPauseButton.Value == 0
                        break;
                    end
                    app.im = readFrame(app.vidObj, "native");
                    pause(1/app.vidObj.FrameRate);
                    app.LeftImage.ImageSource = app.im;
                    app.RightImage.ImageSource = func(app, app.im);
                    app.Slider.Value = app.vidObj.CurrentTime; % changing time
                    app.TimeLabel.Text = num2str(app.vidObj.CurrentTime,'%.2f'); % changing time lable
                end
                if ~(hasFrame(app.vidObj))  % If there is no frame, rest the time
                    app.vidObj.CurrentTime = 0;
                    app.Slider.Value = app.vidObj.CurrentTime;
                    app.TimeLabel.Text = num2str(app.vidObj.CurrentTime, '%.2f');
                end
            else
                app.PlayPauseButton.Icon = 'down.jpg';
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.vidObj.CurrentTime = value; % change the videoObj playing time
            app.TimeLabel.Text = num2str(app.vidObj.CurrentTime,'%.2f');
            app.im = readFrame(app.vidObj);
            app.LeftImage.ImageSource = app.im;
            app.RightImage.ImageSource = func(app, app.im);
        end

        % Value changed function: DELETButton
        function DELETButtonValueChanged(app, event)
            value = app.DELETButton.Value;
            if value
                % if the button pushed, the cell will add string in the end
                app.delete_list{end + 1} = app.vidObj.CurrentTime;
                app.DeleteStatusLabel.Visible = "on";
            else
                % if the button pushed, the cell will add string in the end
                app.delete_list{end + 1} = app.vidObj.CurrentTime;
                app.DeleteStatusLabel.Visible = "off";
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.vidObj.CurrentTime = 0;
            [filename, pathname] = uiputfile({'*.mp4', 'Save a video file'});
            vidObj2 = VideoWriter([pathname filename], "MPEG-4");
            vidObj2.Quality = 95; %defaut 75
            open(vidObj2)
            % setup the waitbar
            water_i = 0;
            wb = waitbar(water_i,'please wait');

            while hasFrame(app.vidObj)
                isDeleted = false;
                current_frame = readFrame(app.vidObj);
                if isempty(app.delete_list)
                    % will save the video directly if the delete list is empty 
                    current_frame = func(app, current_frame); % do image process
                    current_frame = insertText(current_frame, [20 20],app.strl, FontSize=18); % add watermark
                    writeVideo(vidObj2, current_frame);
                    % the wairbar loop
                    waitbar(water_i/150, wb)
                    water_i = water_i + 1;
                else
                    % else save the video that skip the delete duration
                    for i = 1:2:length(app.delete_list)
                        if app.vidObj.CurrentTime >= app.delete_list{i} &&...
                                app.vidObj.CurrentTime <= app.delete_list{i+1} % if the current time in the delete duration
                            isDeleted = true;
                            continue;
                        end
                    end
                    if isDeleted == false
                        current_frame = func(app, current_frame); % do image process
                        current_frame = insertText(current_frame, [20 20], app.strl, FontSize=18); % add watermark
                        writeVideo(vidObj2, current_frame);
                        % the waitbar loop
                        waitbar(water_i/150, wb)
                        water_i = water_i + 1;
                    end                 
                end
            end
            
            if ~isempty(app.delete_list)
                % print the delete list
                fprintf('start\tend\n')
                for i = 1:2:length(app.delete_list)
                    fprintf('%.2f\t%.2f\n', app.delete_list{i}, app.delete_list{i+1})
                end
            end

            delete(wb)
            close(vidObj2)
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)  % close app
        end

        % Menu selected function: WatermarkMenu
        function WatermarkMenuSelected(app, event)
            app.strl = inputdlg("Enter Watermark:", "Watermark", [1 30], {app.strl{1}});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Accelerator = 'S';
            app.SaveasMenu.Text = 'Save as';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'E';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.NegativeMenu = uimenu(app.ProcessMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Accelerator = 'N';
            app.NegativeMenu.Text = 'Negative';
            app.FlipMenu = uimenu(app.ProcessMenu);
            app.FlipMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipMenuSelected, true);
            app.FlipMenu.Accelerator = 'F';
            app.FlipMenu.Text = 'Flip';
            app.BlurMenu = uimenu(app.ProcessMenu);
            app.BlurMenu.MenuSelectedFcn = createCallbackFcn(app, @BlurMenuSelected, true);
            app.BlurMenu.Accelerator = 'B';
            app.BlurMenu.Text = 'Blur';
            app.WatermarkMenu = uimenu(app.UIFigure);
            app.WatermarkMenu.MenuSelectedFcn = createCallbackFcn(app, @WatermarkMenuSelected, true);
            app.WatermarkMenu.Text = 'Watermark';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LeftImage = uiimage(app.LeftPanel);
            app.PlayPauseButton = uibutton(app.LeftPanel, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Icon = fullfile(pathToMLAPP, 'pause.jpg');
            app.PlayPauseButton.Text = '';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TimeLabel = uilabel(app.LeftPanel);
            app.TimeLabel.Text = '';
            app.DELETButton = uibutton(app.LeftPanel, 'state');
            app.DELETButton.ValueChangedFcn = createCallbackFcn(app, @DELETButtonValueChanged, true);
            app.DELETButton.Text = 'DELET';
            app.RightPanel = uipanel(app.UIFigure);
            app.RightImage = uiimage(app.RightPanel);
            app.FilterLabel = uilabel(app.RightPanel);
            app.DeleteStatusLabel = uilabel(app.RightPanel);
            app.DeleteStatusLabel.Text = 'Delete';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw2_M11125016

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end